classdef soundAssignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundAnnotatorUIFigure     matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        Selectaudio1Button         matlab.ui.control.Button
        Selectaudio2Button         matlab.ui.control.Button
        DownloadButton             matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        PlayButton_2               matlab.ui.control.Button
        PauseButton_2              matlab.ui.control.Button
        StopButton_2               matlab.ui.control.Button
        PlayButton_3               matlab.ui.control.Button
        PauseButton_3              matlab.ui.control.Button
        StopButton_3               matlab.ui.control.Button
        SoundAnnotatorMatlabLabel  matlab.ui.control.Label
        AdjustVolumeSliderLabel    matlab.ui.control.Label
        AdjustVolumeSlider         matlab.ui.control.Slider
        AdjustVolumeSlider_2Label  matlab.ui.control.Label
        AdjustVolumeSlider_2       matlab.ui.control.Slider
    end


    properties (Access = private)
        %an object for audio player 1
        playSound1 
        
        %y data of imported audio 1
        varAudioCom1 
        
        %Fs of imported audio 1
        varAudioCom2
        
        % variable for adjusting volume audio1
        audioTemp1
        
        %y data of imported audio 2
        audioCheer1
        
        %Fs of imported audio 2
        audioCheer2
        
        %variable to adjust volume of audio 2
        adjustVol2
        
        %object for audio player 2
        playSound2
        
        %pause for audio track 1
        audioPause1
        
        %pause for audio track 2
        audioPause2
        
        % y data of merged audio
        audio_y
        
        % Fs of merged audio
        audio_fs
        
        %object for mixed audio
        mixedAudio
        
        mixAudioPlay
        
        %pause for mixed audio
        mixPause
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %setting play buttons disable at first
            set(app.PlayButton, "Enable", 'off');
            set(app.PlayButton_2, "Enable", 'off');
            set(app.PlayButton_3, "Enable", 'on');
            
            %setting pause buttons disable
            set(app.PauseButton, "Enable", 'off');
            set(app.PauseButton_2, "Enable", 'off');
            set(app.PauseButton_3, "Enable", 'off');
            
            %setting stop buttons disable
            set(app.StopButton, "Enable", 'off');
            set(app.StopButton_2, "Enable", 'off');
            set(app.StopButton_3, "Enable", 'off');
            
            %setting volume sliders disable
            set(app.AdjustVolumeSlider, "Enable", 'off');
            set(app.AdjustVolumeSlider_2, "Enable", 'off');
            
            %setting download button disable at first
            set(app.DownloadButton, "Enable", 'off');
            
            %setting pause buttons in false state at first
            app.audioPause1 = false;
            app.audioPause2 = false;
            app.mixPause = false;

        end

        % Button pushed function: Selectaudio1Button
        function Selectaudio1ButtonPushed(app, event)
           
            % enabling play button
            set(app.PlayButton, 'Enable', 'on');
            set(app.AdjustVolumeSlider, 'Enable', 'on');
            
            % code to import sound file from file explorer
            
            [openFile, filePath] = uigetfile({'*.mp3;'}, "Select MP3 File");
            imAudio = fullfile(filePath, openFile);
          
            %reading audio file
            [y, Fs] = audioread(imAudio);
           
            %plots the audio file in axes
            plot(app.UIAxes2,y);
            
            app.playSound1 = audioplayer(y,Fs);
           
            app.varAudioCom1 = y;
            app.varAudioCom2 = Fs;
            app.audioTemp1 = y;
        
            %writes the name of imported file the repective button
            app.UIAxes2.Title.String = "Imported: " + openFile;
            
            figure(app.SoundAnnotatorUIFigure);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           
            %enable both pause and stop buttons
            
            set(app.PauseButton, 'Enable', 'on');
            
            set(app.StopButton, 'Enable', 'on');
            
            % check if pause button is enabled or not
            % play audio file when pause button is not pressed 
            if app.audioPause1 == false
                app.playSound1 = audioplayer(app.varAudioCom1,app.varAudioCom2);
                play(app.playSound1);
            
            % pause the file when pause button is pressed
            elseif app.audioPause1 == true
                resume(app.playSound1);
                app.playSound1 = false;
            end
           
        end

        % Button pushed function: Selectaudio2Button
        function Selectaudio2ButtonPushed(app, event)
            
            %enabling play button
            set(app.PlayButton_2, "Enable", 'on');
            set(app.AdjustVolumeSlider_2, 'Enable', 'on');
            
            %opens file from file explorer
            [openFile, filePath] = uigetfile({'*.mp3;'}, "Select MP3 File");
            imAudio = fullfile(filePath, openFile);
          
            %reads the imported audio file
            [y, Fs] = audioread(imAudio);
            
            %plots signal in axes
            plot(app.UIAxes2_2,y);
            
            app.playSound2 = audioplayer(y,Fs);
            
            %set audioCheer1 as y component of audio file
            app.audioCheer1 = y;
            %audioCheer2 is Fs of audio file
            app.audioCheer2 = Fs;
            app.adjustVol2 = y;
            
            %writes the name of imported file the title of axes
            app.UIAxes2_2.Title.String = "Imported: " + openFile;
            
            figure(app.SoundAnnotatorUIFigure);
            
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
           
            %enable both pause and stop buttons
            set(app.PauseButton_2, 'Enable', 'on');
            set(app.StopButton_2, 'Enable', 'on');
            
            %check if pause button is pressed or not
            %if pause button is not pressed then play the audio file without any interruption
            if app.audioPause2 == false
                app.playSound2 = audioplayer(app.audioCheer1,app.audioCheer2);
                play(app.playSound2);
            
            %if pause button is pressed then stop playing audio
            elseif app.audioPause2 == true
                resume(app.playSound2);
                app.playSound2 = false;
            end
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
            %pauses the sound            
            pause(app.playSound1);
            app.playSound1 = true;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           
            %stops playing imported sound 1 while pressing stop
            stop(app.playSound1);
            
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            
            %pauses the imported audio 2 on pressing pause button 2
            
            pause(app.playSound2);
            app.playSound2 = true;
            
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            
            %stops the playing audio file 2 while pressing stop button
            stop(app.playSound2);
            
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
           
            %enable both pause and stop button
            set(app.PauseButton_3, 'Enable', 'on');
            set(app.StopButton_3, 'Enable', 'on');
            
            %check if pause button for merged audio is pressed or not
            %if pause button isn't pressed then play the audio file without any interruption
            if app.mixPause == false
                
                %mix both imported audio
                lmax = min(length(app.audioCheer1), length(app.varAudioCom1));
                app.mixedAudio = app.audioCheer1(1:lmax) + app.varAudioCom1(1:lmax);
            
                app.mixAudioPlay = audioplayer(app.mixedAudio,app.audioCheer2);
                %play merged audio
                play(app.mixAudioPlay);
            
            % if pause button is pressed then pause the audio
            elseif app.mixPause == true
                resume(app.mixAudioPlay);
                app.mixPause = false;
            end
            
            %plots the audio signal of mixed audio in axes
            plot(app.UIAxes,app.mixedAudio);
            
            %enables download button
            set(app.DownloadButton, "Enable", 'on');
          
            
        end

        % Button pushed function: PauseButton_3
        function PauseButton_3Pushed(app, event)
            
            %pauses the audio on pressing pause button
            pause(app.mixAudioPlay);
            app.mixPause = true;
            
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            
            %stops the audio on pressing stop button
            stop(app.mixAudioPlay);
            
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
           
            %opens dialog box to export the file
            [mixAudio_y, mixAudio_Fs] = uiputfile('.wav', 'Save Merged Audio File', 'mergedSoundTrack.wav');
            new = fullfile(mixAudio_Fs, mixAudio_y);
            
            %writes the audio
            audiowrite(new, app.mixedAudio, app.audioCheer2);
            
            % opens message dialog
            msgbox("Mixed audio file: " + mixAudio_y + "has been saved to: " + mixAudio_Fs + ".");
           
            
        end

        % Value changed function: AdjustVolumeSlider
        function AdjustVolumeSliderValueChanged(app, event)
          
            value = app.AdjustVolumeSlider.Value;
            %clears the sound that is playing
            clear sound;
            
            %initializing the value
            audioVolume = value/10;
            
            app.varAudioCom1 = app.audioTemp1;
            
            %if condition to check if the value of slider is greater than 60 or lesser than 50
            if value > 60
                %increases the volume if value of slider is greater than 60
                app.varAudioCom1 = app.varAudioCom1*audioVolume;
            elseif value < 50
                %decreasest the volume if the value of slider is lesser than 50
                app.varAudioCom1 = app.varAudioCom1/audioVolume;
            else
                app.varAudioCom1 = app.audioTemp1;
            end
            
        end

        % Value changed function: AdjustVolumeSlider_2
        function AdjustVolumeSlider_2ValueChanged(app, event)
        
            value = app.AdjustVolumeSlider_2.Value;
            %clears the sound i.e. stops the audio that is playing
            clear sound;
            %initialising the value of volume
            volOfSecondAudio = value/10;
            
            app.audioCheer1 = app.adjustVol2;
            
            %check if the value of slider is greater or lesser
            if value > 60
                %increases the volume of sound if the value of slider is greater than 60
                app.audioCheer1 = app.audioCheer1*volOfSecondAudio;
            elseif value < 40
                %decreases the volume of sound if the value of slider is lesser than 40
                app.audioCheer1 = app.audioCheer1/volOfSecondAudio;
            else
                app.varAudioCom1 = app.adjustVol2;
            end
            
        end

        % Callback function
        function ResumeButtonPushed(app, event)
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundAnnotatorUIFigure = uifigure;
            app.SoundAnnotatorUIFigure.Name = 'Sound Annotator';
            app.UIAxes = uiaxes(app.SoundAnnotatorUIFigure);
            title(app.UIAxes, 'Mixed Sound Track')
            app.UIAxes2 = uiaxes(app.SoundAnnotatorUIFigure);
            title(app.UIAxes2, 'Audio 1')
            app.UIAxes2_2 = uiaxes(app.SoundAnnotatorUIFigure);
            title(app.UIAxes2_2, 'Audio 2')
            app.Selectaudio1Button = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.Selectaudio1Button.ButtonPushedFcn = createCallbackFcn(app, @Selectaudio1ButtonPushed, true);
            app.Selectaudio1Button.Text = 'Select audio 1';
            app.Selectaudio2Button = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.Selectaudio2Button.ButtonPushedFcn = createCallbackFcn(app, @Selectaudio2ButtonPushed, true);
            app.Selectaudio2Button.Text = 'Select audio 2';
            app.DownloadButton = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.PlayButton = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.StopButton_2 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.PlayButton_3 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.PauseButton_3 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.PauseButton_3.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_3Pushed, true);
            app.PauseButton_3.Text = 'Pause';
            app.StopButton_3 = uibutton(app.SoundAnnotatorUIFigure, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.SoundAnnotatorMatlabLabel = uilabel(app.SoundAnnotatorUIFigure);
            app.SoundAnnotatorMatlabLabel.Text = 'Sound Annotator Matlab';
            app.AdjustVolumeSliderLabel = uilabel(app.SoundAnnotatorUIFigure);
            app.AdjustVolumeSliderLabel.Text = 'Adjust Volume';
            app.AdjustVolumeSlider = uislider(app.SoundAnnotatorUIFigure);
            app.AdjustVolumeSlider.MajorTicks = [0 20 40 60 80 100];
            app.AdjustVolumeSlider.ValueChangedFcn = createCallbackFcn(app, @AdjustVolumeSliderValueChanged, true);
            app.AdjustVolumeSlider_2Label = uilabel(app.SoundAnnotatorUIFigure);
            app.AdjustVolumeSlider_2Label.Text = 'Adjust Volume';
            app.AdjustVolumeSlider_2 = uislider(app.SoundAnnotatorUIFigure);
            app.AdjustVolumeSlider_2.MajorTicks = [0 30 60 100];
            app.AdjustVolumeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AdjustVolumeSlider_2ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = soundAssignment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoundAnnotatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundAnnotatorUIFigure)
        end
    end
end